classdef maintenance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CLRButton                  matlab.ui.control.Button
        RESULTEditField            matlab.ui.control.EditField
        RESULTEditFieldLabel       matlab.ui.control.Label
        CALCULATEButton            matlab.ui.control.Button
        HistoryEditField           matlab.ui.control.EditField
        HistoryEditFieldLabel      matlab.ui.control.Label
        UsageEditField             matlab.ui.control.EditField
        UsageEditFieldLabel        matlab.ui.control.Label
        MachineTypeEditField       matlab.ui.control.EditField
        MachineTypeEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            
            % Prompt user for machine type
            machine_type = input('Enter machine type: ','s');
            
            % Prompt user for machine usage data
            usage = input('Enter machine usage data as a vector: ');
            
            % Prompt user for maintenance history data
            history = input('Enter maintenance history data as a vector: ');
            
            % Define maintenance thresholds (in hours)
            pm_threshold = 500;
            plm_threshold = 800;
            cm_threshold = 1000;
            
            % Calculate total usage time
            total_usage = sum(usage);
            
            % Calculate average usage per day (assuming 8-hour workdays)
            avg_usage = sum(usage) / (length(usage) * 8);
            
            % Calculate total maintenance time
            total_maintenance = sum(history);
            
            % Calculate average time between maintenance events
            if length(history) > 1
            avg_maintenance = (history(end) - history(1)) / (length(history) - 1);
            else
            avg_maintenance = Inf;
            end
            
            % Determine type of maintenance needed
            if total_usage >= cm_threshold || avg_usage >= cm_threshold/8 || total_maintenance >= cm_threshold || avg_maintenance <= cm_threshold
            maintenance_type = 'Corrective Maintenance';
            maintenance_date = datetime('today') + caldays(30); % Maintenance needed in 30 days for corrective maintenance
            elseif total_usage >= plm_threshold || avg_usage >= plm_threshold/8 || total_maintenance >= plm_threshold || avg_maintenance <= plm_threshold
            maintenance_type = 'Planned Maintenance';
            maintenance_date = datetime('today') + caldays(60); % Maintenance needed in 60 days for planned maintenance
            elseif total_usage >= pm_threshold || avg_usage >= pm_threshold/8 || total_maintenance >= pm_threshold || avg_maintenance <= pm_threshold
            maintenance_type = 'Preventive Maintenance';
            maintenance_date = datetime('today') + caldays(90); % Maintenance needed in 90 days for preventive maintenance
            else
            maintenance_type = 'No Maintenance Needed';
            maintenance_date = 'N/A';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MachineTypeEditFieldLabel = uilabel(app.UIFigure);
            app.MachineTypeEditFieldLabel.Text = 'Machine Type';
            app.MachineTypeEditField = uieditfield(app.UIFigure, 'text');
            app.MachineTypeEditField.Tag = 'machine_type';
            app.UsageEditFieldLabel = uilabel(app.UIFigure);
            app.UsageEditFieldLabel.Text = 'Usage';
            app.UsageEditField = uieditfield(app.UIFigure, 'text');
            app.UsageEditField.Tag = 'usage';
            app.HistoryEditFieldLabel = uilabel(app.UIFigure);
            app.HistoryEditFieldLabel.Tag = 'history';
            app.HistoryEditFieldLabel.Text = 'History';
            app.HistoryEditField = uieditfield(app.UIFigure, 'text');
            app.CALCULATEButton = uibutton(app.UIFigure, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE';
            app.RESULTEditFieldLabel = uilabel(app.UIFigure);
            app.RESULTEditFieldLabel.Text = 'RESULT';
            app.RESULTEditField = uieditfield(app.UIFigure, 'text');
            app.CLRButton = uibutton(app.UIFigure, 'push');
            app.CLRButton.Text = 'CLR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = maintenance

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end